classdef TOF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VoltageMeter                   matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ConnectionTab                  matlab.ui.container.Tab
        DeviceListDropDownLabel        matlab.ui.control.Label
        DeviceListDropDown             matlab.ui.control.DropDown
        ConnectButton                  matlab.ui.control.Button
        WaitforconnectionLabel         matlab.ui.control.Label
        ConfigurationTab               matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        SendandreceivePanel            matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        NumberofpulsetosendEditFieldLabel  matlab.ui.control.Label
        NumberofpulsetosendEditField   matlab.ui.control.NumericEditField
        NumberofpulsetoreceiveEditFieldLabel  matlab.ui.control.Label
        NumberofpulsetoreceiveEditField  matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        MaxofTimeofFlightusDropDownLabel  matlab.ui.control.Label
        MaxofTimeofFlightusDropDown    matlab.ui.control.DropDown
        TriggerPanel                   matlab.ui.container.Panel
        GatingTimeusEditFieldLabel     matlab.ui.control.Label
        GatingTimeusEditField          matlab.ui.control.NumericEditField
        TriggerThresholdmVEditFieldLabel  matlab.ui.control.Label
        TriggerThresholdmVEditField    matlab.ui.control.NumericEditField
        mV62mVLabel                    matlab.ui.control.Label
        usNN3216Label                  matlab.ui.control.Label
        UploadParametersButton         matlab.ui.control.Button
        UIAxes2_2                      matlab.ui.control.UIAxes
        iTOFMeasurementsTab            matlab.ui.container.Tab
        CaptureButton_2                matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        TemperatureLabel               matlab.ui.control.Label
        dTOFMeasurementsTab            matlab.ui.container.Tab
        UIAxes_4                       matlab.ui.control.UIAxes
        TemperatureLabel_2             matlab.ui.control.Label
        dTOFmeasurementsButton         matlab.ui.control.Button
        BuildReferenceButton           matlab.ui.control.Button
        ReferencesignalisnotavailableLabel  matlab.ui.control.Label
        CalibrationtemperatureTab      matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        CalibrationButton              matlab.ui.control.Button
        TOFvsTimeTab                   matlab.ui.container.Tab
        UIAxes_5                       matlab.ui.control.UIAxes
        CaptureButton_3                matlab.ui.control.Button
        STOPButton_2                   matlab.ui.control.Button
        ClearButton_2                  matlab.ui.control.Button
        DebugButton_2                  matlab.ui.control.Button
        TOFTESTTab                     matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        A0VEditFieldLabel              matlab.ui.control.Label
        A0VEditField                   matlab.ui.control.NumericEditField
        CaptureButton                  matlab.ui.control.Button
        VoltageRangeDropDownLabel      matlab.ui.control.Label
        VoltageRangeDropDown           matlab.ui.control.DropDown
        AutoScaleButton                matlab.ui.control.Button
        SamplingRateLabel              matlab.ui.control.Label
        SamplingRateDropDown_2         matlab.ui.control.DropDown
        A1VEditFieldLabel              matlab.ui.control.Label
        A1VEditField                   matlab.ui.control.NumericEditField
        STOPButton                     matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        A2VEditFieldLabel              matlab.ui.control.Label
        A2VEditField                   matlab.ui.control.NumericEditField
        A3VEditFieldLabel              matlab.ui.control.Label
        A3VEditField                   matlab.ui.control.NumericEditField
        A0ChannelCheckBox              matlab.ui.control.CheckBox
        A1ChannelCheckBox              matlab.ui.control.CheckBox
        A2ChannelCheckBox              matlab.ui.control.CheckBox
        A3ChannelCheckBox              matlab.ui.control.CheckBox
        slowerforhighaccuracyLabel     matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        ReversePolarityAveragingCheckBox  matlab.ui.control.CheckBox
        EnableBufferCheckBox           matlab.ui.control.CheckBox
        DocTab                         matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        DebugButton                    matlab.ui.control.Button
        CalibrationTDCTab              matlab.ui.container.Tab
        CalibrationofTDCPanel          matlab.ui.container.Panel
        GatingTimeusEditField_4Label   matlab.ui.control.Label
        GatingTimeusEditField_4        matlab.ui.control.NumericEditField
        TriggerThresholdmVEditField_4Label  matlab.ui.control.Label
        TriggerThresholdmVEditField_4  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
       
       Continue;  % continue capturing
       Maxpoints=50000; % max sampling depth
       Voltage_per_bit=3.42;
       current_ratio=1.9963;
       
       bufferSizeA = 1e6;  % Large buffer for storing all incoming data
       bufferSizeB = 500; % Small buffer for plotting
     
       
       bufferIndexA = 1;  % Write index for buffer A
       bufferIndexB = 1;  % Write index for buffer B
       % register0
       sel_tsto1 = 0;
       sel_tsto2 = 0;
       
       sel_timo_mb=2;
       hitin=9;
      
       neg_stop=0;
       neg_start=0; 
       start_clkhs=2;
       div_clkhs=0;
       div_fire=3;
       anz_fire=8;
       
       %register1
       offset=0;
       DELVAL1=120;
       RFEDGE=0;
       EN_INT_ALU=1;
       EN_INT_HIT=0;
       EN_INT_TO=0;


       % temperature cal
       R0_calibration=1000;


    end
    
    properties (Access = public)
        time; 
        A;
        B;
       % buffer for channels
       bufferA=[];
       %buffer for plotting
       bufferB=[];
       row_index=[];
        A_mean=2036;
        B_mean=2045;
        % Description
         % TOF Data
       rawData;
       InstrumentObject ; % the serial port that retrieve the data
    end


    methods (Access = private)
        
        function ChannelRegister(app)
            if (app.A0ChannelCheckBox.Value==1)
               app.Channel0P=0;
               app.Channel0N=1;
           else
               app.Channel0P=[];
               app.Channel0N=[];
            end
            
            if (app.A1ChannelCheckBox.Value==1)
               app.Channel1P=16*2;
               app.Channel1N=3;
           else
               app.Channel1P=[];
               app.Channel1N=[];
            end
            
            if (app.A2ChannelCheckBox.Value==1)
               app.Channel2P=16*4;
               app.Channel2N=5;
           else
               app.Channel2P=[];
               app.Channel2N=[];
            end
            
            if (app.A3ChannelCheckBox.Value==1)
               app.Channel3P=16*6;
               app.Channel3N=7;
           else
               app.Channel3P=[];
               app.Channel3N=[];
            end
            
         end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         % Find all available instruments
           ports=seriallist;
         % initial all buffers
%            app.bufferA = NaN(4,app.bufferSizeA);
%            app.bufferB = NaN(4,app.bufferSizeB);
           app.DeviceListDropDown.Items=ports; 
%            set(app.UIAxes,'linewidth',1,'FontSize',20)
%            app.UIAxes.XLabel.String='Counts';
%            app.UIAxes.YLabel.String='Voltage (V)';
           
           
           set(app.UIAxes_5,'linewidth',1,'FontSize',20)
           app.UIAxes_5.XLabel.String='Time (s)';
           app.UIAxes_5.YLabel.String='TOF (ns)';
           
           yyaxis(app.UIAxes_5,'right')
           app.UIAxes_5.YLabel.String='Temperature (^0 C)';
           
           
           imshow('TOF.jpg', 'Parent', app.UIAxes2)
           imshow('trigger.png', 'Parent', app.UIAxes2_2)


           % yyaxis(app.UIAxes,'right')
           % app.UIAxes.YLabel.String='Current (A)';
           
           %  assignin('base', 'TOF', app);
           
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Get the selected item from the dropdown
            
            obj=serial(app.DeviceListDropDown.Value,'BaudRate',4800);
            app.InstrumentObject=obj;
            
            set(app.InstrumentObject, 'InputBufferSize', 5000000);
           % Open the serial port
            fopen(app.InstrumentObject);
            app.WaitforconnectionLabel.Text='Connected';
            
        end

        % Close request function: VoltageMeter
        function VoltageMeterCloseRequest(app, event)
            s= instrfind;
           if isempty(s)
           else
           fclose(s);
           delete(s);
            clear s;
           end
            delete(app)
            
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            % stop the capture action
            STOPButtonPushed(app, event);
            app.NumberOfChannels=app.A0ChannelCheckBox.Value+app.A1ChannelCheckBox.Value+app.A2ChannelCheckBox.Value+app.A3ChannelCheckBox.Value;
            % 1st byte, gain or voltage range
            % 2nd byte, data rate
            % 3rd, number of selected channels
            % 4th-11th bytes, registers for channels as uint16, if a channel is unselected, channel_x will be assign to empty
            % 12 byte, 1 for buffer on, 0 for buffer off
            app.ChannelRegister();
            configuration_register = [uint8(str2double(app.VoltageRangeDropDown.Value)), uint8(str2double(app.SamplingRateDropDown_2.Value)), uint8(app.NumberOfChannels), uint8(app.Channel0P),uint8(app.Channel0N),uint8(app.Channel1P),uint8(app.Channel1N),uint8(app.Channel2P),uint8(app.Channel2N),uint8(app.Channel3P), uint8(app.Channel3N),uint8(app.EnableBufferCheckBox.Value)]; % Array of uint8 values
            % filter out the empty bytes
            configuration_register = configuration_register(~cellfun(@isempty, num2cell(configuration_register)));
            % send the filtered array to stm32
            fwrite(app.InstrumentObject,configuration_register,'uint8');
            % set continue as 1
            app.Continue=1;
            
             % number of capturing channels
             app.row_index=zeros(1,configuration_register(3));
             for i=1:configuration_register(3)
                 % calculate the buffer index
                 app.row_index(i)=configuration_register(2+2*i)/32+1;
             end
            
            
            while (app.Continue==1)
               
               if  app.InstrumentObject.BytesAvailable>4
               rawData = fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable, "uint8");
        
                % Convert 4 bytes to float
               % if mod(length(rawData),4)~=0
                %   app.slowerforhighaccuracyLabel.Text='loss data';
                % end
                
                numFloats = floor(length(rawData) / 4);
                % keyboard
                floatData =reshape( typecast(uint8(rawData(1:numFloats*4)), 'single'),double(configuration_register(3)),numFloats/double(configuration_register(3)));


                % Store in Buffer A using Circular Indexing
                 numNewData = numFloats/double(configuration_register(3));
                 endIndexA = app.bufferIndexA + numNewData - 1;


        if endIndexA <= app.bufferSizeA
            app.bufferA(app.row_index,app.bufferIndexA:endIndexA) = floatData;
        else
            splitPoint = app.bufferSizeA - app.bufferIndexA + 1;
            app.bufferA(app.row_index,app.bufferIndexA:end) = floatData(1:splitPoint);
            app.bufferA(app.row_index,1:numNewData - splitPoint) = floatData(splitPoint + 1:end);
        end
        
        % Update bufferIndexA (circular wrap-around)
        app.bufferIndexA = mod(app.bufferIndexA + numNewData - 1, app.bufferSizeA) + 1;
        
        % Select 1 point from bufferA to store in bufferB (Downsampling)
        app.bufferB(app.row_index,app.bufferIndexB) = floatData(:,end);  % Pick latest value
        % demostrate the conversion result in the text
        
        % Update bufferIndexB (circular wrap-around)
        app.bufferIndexB = mod(app.bufferIndexB, app.bufferSizeB) + 1;
        
        
        % Update plot
        for i=1:app.NumberOfChannels
         plot(app.UIAxes, app.bufferB(app.row_index(i),:));
         hold(app.UIAxes,'on')
         
        end
             
          hold(app.UIAxes,'off')   
               end   
                pause(0.001)  
              drawnow
             
            end 


%             app.Continue=0; 
%             % flush input buffer
%             if (app.InstrumentObject.BytesAvailable>0)
%                 fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable);
%             end
%             
%             value = app.A0VEditField.Value;
%             if (value>1/(str2double(app.VoltageRangeDropDown.Value)*1e6)*app.Maxpoints*1e3)
%                 app.A0VEditField.Value=1/(str2double(app.VoltageRangeDropDown.Value)*1e6)*app.Maxpoints*1e3;
%             end
%             
%             
%             cla(app.UIAxes) 
%             sysfrequency=125;
%             period=1/(str2double(app.VoltageRangeDropDown.Value)*1e6);
%             div=sysfrequency/str2double(app.VoltageRangeDropDown.Value);
%             length_of_buf= uint16(app.A0VEditField.Value*1e-3*str2double(app.VoltageRangeDropDown.Value)*1e6);
%              
%             fwrite(app.InstrumentObject,div,'uint8');
%             fwrite(app.InstrumentObject,length_of_buf,'uint16');
%             
%             dataStr = fscanf(app.InstrumentObject, '%s');  % Read all incoming data as a string
%             dataLen=length(dataStr);
%             % Split the string into 8-character groups (each representing one uint32)
%             numGroups = dataLen / 8;
%             receivedData = reshape(dataStr, 8, numGroups)';  % Reshape into a matrix where each row is 8 characters
% 
%            % Convert each group of 8 characters from hex string to uint32
%            uint32Values = uint32(hex2dec(receivedData));
%            % Initialize arrays to store the split data
%            
%            [app.A, app.B] = app.extractData(uint32Values);
%            app.time=0:period:period*double((length_of_buf-1));
%           
%           
%            x=app.time;
%            yyaxis(app.UIAxes,'left') 
%            plot(app.UIAxes,x,double(app.A)-app.A_mean)
%            hold(app.UIAxes,'on')
%            yyaxis(app.UIAxes,'right')
%            plot(app.UIAxes,x,double(app.B)-app.B_mean)
%            hold(app.UIAxes,'off')


        end

        % Value changed function: A0VEditField
        function A0VEditFieldValueChanged(app, event)
           
            value = app.A0VEditField.Value;
            if (value>1/(str2double(app.VoltageRangeDropDown.Value)*1e6)*app.Maxpoints*1e3)
                app.A0VEditField.Value=1/(str2double(app.VoltageRangeDropDown.Value)*1e6)*app.Maxpoints*1e3;
            end
                
        end

        % Callback function
        function AutoButtonPushed(app, event)
            if (app.InstrumentObject.BytesAvailable>0)
                fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable);
            end
            
            
            sysfrequency=125;
            period=1/(str2double(app.VoltageRangeDropDown.Value)*1e6);
            div=sysfrequency/str2double(app.VoltageRangeDropDown.Value);
            length_of_buf= uint16(512);
            app.Continue=1;
            while (app.Continue==1)
                fwrite(app.InstrumentObject,div,'uint8');
                fwrite(app.InstrumentObject,length_of_buf,'uint16');
                
                dataStr = fscanf(app.InstrumentObject, '%s');  % Read all incoming data as a string
                dataLen=length(dataStr);
                % Split the string into 8-character groups (each representing one uint32)
                numGroups = dataLen / 8;
                receivedData = reshape(dataStr, 8, numGroups)';  % Reshape into a matrix where each row is 8 characters
                
                % Convert each group of 8 characters from hex string to uint32
                uint32Values = uint32(hex2dec(receivedData));
                % Initialize arrays to store the split data
                
                [app.A, app.B] = app.extractData(uint32Values);
                app.time=0:period:period*double((length_of_buf-1));
                
                
                x=app.time;
                cla(app.UIAxes)
                yyaxis(app.UIAxes,'left')
                plot(app.UIAxes,x,double(app.A)-app.A_mean)
                hold(app.UIAxes,'on')
                yyaxis(app.UIAxes,'right')
                plot(app.UIAxes,x,double(app.B)-app.B_mean)
                hold(app.UIAxes,'off')
                pause(0.1); drawnow;
                 
                  if (app.InstrumentObject.BytesAvailable>0)
                fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable);
                  end
           
            end
            
                
        end

        % Button pushed function: AutoScaleButton
        function AutoScaleButtonPushed(app, event)
            axis(app.UIAxes, 'tight'); 
        end

        % Value changed function: VoltageRangeDropDown
        function VoltageRangeDropDownValueChanged(app, event)
            value = app.VoltageRangeDropDown.Value;
            
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
             keyboard
        end

        % Value changed function: A0ChannelCheckBox
        function A0ChannelCheckBoxValueChanged(app, event)
            value = app.A0ChannelCheckBox.Value;
           
        end

        % Value changed function: A1ChannelCheckBox
        function A1ChannelCheckBoxValueChanged(app, event)
            value = app.A1ChannelCheckBox.Value;
            if (value==1)
               app.Channel1P=32;
               app.Channel1N=3;
           else
               app.Channel1P=[];
               app.Channel1N=[];
           end
        end

        % Value changed function: A2ChannelCheckBox
        function A2ChannelCheckBoxValueChanged(app, event)
            value = app.A2ChannelCheckBox.Value;
            if (value==1)
               app.Channel1P=16*4;
               app.Channel1N=5;
           else
               app.Channel1P=[];
               app.Channel1N=[];
           end
        end

        % Value changed function: A3ChannelCheckBox
        function A3ChannelCheckBoxValueChanged(app, event)
            value = app.A3ChannelCheckBox.Value;
            if (value==1)
               app.Channel1P=16*6;
               app.Channel1N=7;
           else
               app.Channel1P=[];
               app.Channel1N=[];
           end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            % write 3 0 bytes to stm32 to stop capturing
            fwrite(app.InstrumentObject, [0, 0, 0], "uint8");
            % set continue as 0
            app.Continue=0;
             if (app.InstrumentObject.BytesAvailable>0)
                fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable);
             end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            %clear 
             app.bufferIndexA = 1;  % Write index for buffer A
             app.bufferIndexB = 1;
             app.bufferA = NaN(4,app.bufferSizeA);
             app.bufferB = NaN(4,app.bufferSizeB);
             
        end

        % Value changed function: NumberofpulsetosendEditField
        function NumberofpulsetosendEditFieldValueChanged(app, event)
            value = floor(app.NumberofpulsetosendEditField.Value);
            
            if value>127
                value=127;
            end
            
            if value<1
                value=1;
            end
            
            app.NumberofpulsetosendEditField.Value=value;
            
            app.anz_fire=value;
        end

        % Callback function
        function DrivingfrequencyMHzEditFieldValueChanged(app, event)
            value = app.DrivingfrequencyMHzEditField.Value;
            
            
        end

        % Value changed function: NumberofpulsetoreceiveEditField
        function NumberofpulsetoreceiveEditFieldValueChanged(app, event)
            value = floor(app.NumberofpulsetoreceiveEditField.Value);
            
             if value>8
                value=8;
            end
            
            if value<1
                value=1;
            end
            
            app.NumberofpulsetoreceiveEditField.Value=value;
            
            app.hitin=value+1;


        end

        % Value changed function: MaxofTimeofFlightusDropDown
        function MaxofTimeofFlightusDropDownValueChanged(app, event)
            value = app.MaxofTimeofFlightusDropDown.Value;
            app.sel_timo_mb=value;
        end

        % Value changed function: GatingTimeusEditField
        function GatingTimeusEditFieldValueChanged(app, event)
            value = app.GatingTimeusEditField.Value;
            
            if value>2^16-1
                value=2^16-1;
            end
            
            if value<0.75
                value=0.75;
            end
            
            app.GatingTimeusEditField.Value=value;
            app.DELVAL1=floor(value/0.25);
            
        end

        % Value changed function: TriggerThresholdmVEditField
        function TriggerThresholdmVEditFieldValueChanged(app, event)
            value = app.TriggerThresholdmVEditField.Value;
            
            if value>62
                value=62;
            end
            
            if value<-64
                value=-64;
            end
            
            app.TriggerThresholdmVEditField.Value=value;
            
             if value >= 0
        % 正电压: 0-62 mV -> 编码 0-31
        app.offset = floor(value / 2);
    else
        % 负电压: -64 mV 到 -2 mV -> 编码 32-63
        app.offset = floor((value / 2) + 64);
             end
        end

        % Button pushed function: CaptureButton_2
        function CaptureButton_2Pushed(app, event)
            % trigger tof measurements
            fwrite(app.InstrumentObject,2,'uint8');
            % wait enough tof data
            while (app.InstrumentObject.BytesAvailable<8000)
                pause(1);
            end
            
            % retrieve the tof data
            app.rawData=fread(app.InstrumentObject,2000,'uint32')/2^16*250;
            h = histogram(app.UIAxes_2, app.rawData, 'FaceColor', 'blue', 'FaceAlpha', 0.7,'NumBins',50);
            
            % 计算统计量
            mean_val = mean(app.rawData);
            std_val = std(app.rawData);
            
            
            % 设置标题，显示统计信息
            title(app.UIAxes_2, sprintf('TOF distribution - Mean: %.2f, STD: %.2f', mean_val, std_val));
            
            % 获取温度
            resistance=fread(app.InstrumentObject,1,'float');
            temperature = pt1000_to_temperature(resistance, app.R0_calibration);
                
            app.TemperatureLabel.Text=num2str(temperature);


        end

        % Button pushed function: UploadParametersButton
        function UploadParametersButtonPushed(app, event)
            % form the two registers
            reg0 = build_write_reg0_param(app.sel_tsto1, app.sel_tsto2, app.sel_timo_mb, app.hitin, ...
                                     app.neg_stop, app.neg_start, app.start_clkhs, app.div_clkhs, ...
                                     app.div_fire, app.anz_fire);
            reg1 = build_write_reg1_param(app.offset, app.DELVAL1, app.RFEDGE, app.EN_INT_ALU, app.EN_INT_HIT, app.EN_INT_TO);
            
            %trigger the upload
            fwrite(app.InstrumentObject,1,'uint8');
            % write to mcu
            fwrite(app.InstrumentObject,reg0,'uint32');
            fwrite(app.InstrumentObject,reg1,'uint32');


        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            % send 3 trigger caliberation
            fwrite(app.InstrumentObject,3,'uint8');
             % wait enough tof data
            while (app.InstrumentObject.BytesAvailable<800)
                pause(1);
            end
            
            % retrieve the resistance
            app.rawData=fread(app.InstrumentObject,200,'float');
            temperature=zeros(length(app.rawData),1);
            % compute the temperature
            for i=1:length(app.rawData)
                temperature(i) = pt1000_to_temperature(app.rawData(i), app.R0_calibration);
            
            end
            
            h = histogram(app.UIAxes_3, temperature, 'FaceColor', 'blue', 'FaceAlpha', 0.7,'NumBins',50);


            % 计算统计量
            mean_val = mean(temperature);
            std_val = std(temperature);
            
            
            % 设置标题，显示统计信息
            title(app.UIAxes_3, sprintf('Temperature distribution - Mean: %.2f, STD: %.4f', mean_val, std_val));
            
        end

        % Button pushed function: dTOFmeasurementsButton
        function dTOFmeasurementsButtonPushed(app, event)
             % send 4 trigger dTOF measurements
            fwrite(app.InstrumentObject,4,'uint8');
            
            % wait enough tof data
            while (app.InstrumentObject.BytesAvailable<8000)
                pause(1);
            end
            
            % retrieve the tof data
            app.rawData=fread(app.InstrumentObject,2000,'int32')/2^16*250;
            h = histogram(app.UIAxes_4, app.rawData, 'FaceColor', 'blue', 'FaceAlpha', 0.7,'NumBins',50);
            
            % 计算统计量
            mean_val = mean(app.rawData);
            std_val = std(app.rawData);
            
            
            % 设置标题，显示统计信息
            title(app.UIAxes_4, sprintf('TOF distribution - Mean: %.2f, STD: %.2f', mean_val, std_val));
            
            % 获取温度
            resistance=fread(app.InstrumentObject,1,'float');
            temperature = pt1000_to_temperature(resistance, app.R0_calibration);
                
            app.TemperatureLabel_2.Text=num2str(temperature);
            
        end

        % Button pushed function: BuildReferenceButton
        function BuildReferenceButtonPushed(app, event)
            app.ReferencesignalisnotavailableLabel.Text='Reference signal is not available';
            % send 5 trigger dTOF measurements
            fwrite(app.InstrumentObject,5,'uint8');
            % 参考信号获取flag
             % wait enough tof data
            while (app.InstrumentObject.BytesAvailable<1)
                pause(1);
            end
            
            dumy=fread(app.InstrumentObject,1,'uint8');
            
            app.ReferencesignalisnotavailableLabel.Text='Reference signal is captured';
            
            
        end

        % Button pushed function: CaptureButton_3
        function CaptureButton_3Pushed(app, event)
            % capture continuesly
            app.Continue=1;
            tic
            
            while (app.Continue==1)
                
                % send 6 to trigger short tof measurements
                fwrite(app.InstrumentObject,6,'uint8');
                % retrieve the short tof data, and merge to A
                  % wait enough tof data
            while (app.InstrumentObject.BytesAvailable<4)
                pause(1);
            end
                tof_meta=fread(app.InstrumentObject,1,'uint32')/2^16*250;
                app.A=[app.A,tof_meta];
                
                       % wait enough tof data
            while (app.InstrumentObject.BytesAvailable<4)
                pause(1);
            end
                % retrieve the temperature
                resistance=fread(app.InstrumentObject,1,'float');
                temperature = pt1000_to_temperature(resistance, app.R0_calibration);
                app.B=[app.B,temperature];
                % retrieve the time
                app.time=[app.time,toc];
                
                x=app.time;
                cla(app.UIAxes_5)
                yyaxis(app.UIAxes_5,'left')
                plot(app.UIAxes_5,x,app.A)
                hold(app.UIAxes_5,'on')
                yyaxis(app.UIAxes_5,'right')
                plot(app.UIAxes_5,x,app.B)
                hold(app.UIAxes_5,'off')
                pause(0.1); drawnow;


            end


%                 numFloats = floor(length(rawData) / 4);
%                 % keyboard
%                 floatData =reshape( typecast(uint8(rawData(1:numFloats*4)), 'single'),double(configuration_register(3)),numFloats/double(configuration_register(3)));
%                 
%                 
%                 
%                 % Store in Buffer A using Circular Indexing
%                 numNewData = numFloats/double(configuration_register(3));
%                 endIndexA = app.bufferIndexA + numNewData - 1;
%                 
%                 
%                 
%                 if endIndexA <= app.bufferSizeA
%                     app.bufferA(app.row_index,app.bufferIndexA:endIndexA) = floatData;
%                 else
%                     splitPoint = app.bufferSizeA - app.bufferIndexA + 1;
%                     app.bufferA(app.row_index,app.bufferIndexA:end) = floatData(1:splitPoint);
%                     app.bufferA(app.row_index,1:numNewData - splitPoint) = floatData(splitPoint + 1:end);
%                 end
%                 
%                 % Update bufferIndexA (circular wrap-around)
%                 app.bufferIndexA = mod(app.bufferIndexA + numNewData - 1, app.bufferSizeA) + 1;
%                 
%                 % Select 1 point from bufferA to store in bufferB (Downsampling)
%                 app.bufferB(app.row_index,app.bufferIndexB) = floatData(:,end);  % Pick latest value
%                 % demostrate the conversion result in the text
%                 
%                 % Update bufferIndexB (circular wrap-around)
%                 app.bufferIndexB = mod(app.bufferIndexB, app.bufferSizeB) + 1;
%                 
%                 
%                 % Update plot
%                 for i=1:app.NumberOfChannels
%                     plot(app.UIAxes, app.bufferB(app.row_index(i),:));
%                     hold(app.UIAxes,'on')
%                     
%                 end
%                 
%                 hold(app.UIAxes,'off')
%             end
%             pause(0.001)
%             drawnow
%             
%         end
        
        
        end

        % Button pushed function: STOPButton_2
        function STOPButton_2Pushed(app, event)
            app.Continue=0;
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            % clear A B Time
            app.A=[];
            app.B=[];
            app.time=[];
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
        end

        % Button pushed function: DebugButton_2
        function DebugButton_2Pushed(app, event)
            keyboard;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VoltageMeter = uifigure;
            app.VoltageMeter.Name = 'Ultrasonic TOF';
            app.VoltageMeter.CloseRequestFcn = createCallbackFcn(app, @VoltageMeterCloseRequest, true);
            app.TabGroup = uitabgroup(app.VoltageMeter);
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.DeviceListDropDownLabel = uilabel(app.ConnectionTab);
            app.DeviceListDropDownLabel.Text = 'Device List';
            app.DeviceListDropDown = uidropdown(app.ConnectionTab);
            app.ConnectButton = uibutton(app.ConnectionTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.WaitforconnectionLabel = uilabel(app.ConnectionTab);
            app.WaitforconnectionLabel.Text = 'Wait for connection';
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.UIAxes2 = uiaxes(app.ConfigurationTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SendandreceivePanel = uipanel(app.ConfigurationTab);
            app.SendandreceivePanel.Title = 'Send and receive';
            app.Label_2 = uilabel(app.SendandreceivePanel);
            app.Label_2.Text = '1~8';
            app.NumberofpulsetosendEditFieldLabel = uilabel(app.SendandreceivePanel);
            app.NumberofpulsetosendEditFieldLabel.Text = 'Number of pulse to send';
            app.NumberofpulsetosendEditField = uieditfield(app.SendandreceivePanel, 'numeric');
            app.NumberofpulsetosendEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofpulsetosendEditFieldValueChanged, true);
            app.NumberofpulsetosendEditField.Value = 8;
            app.NumberofpulsetoreceiveEditFieldLabel = uilabel(app.SendandreceivePanel);
            app.NumberofpulsetoreceiveEditFieldLabel.Text = 'Number of pulse to receive';
            app.NumberofpulsetoreceiveEditField = uieditfield(app.SendandreceivePanel, 'numeric');
            app.NumberofpulsetoreceiveEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofpulsetoreceiveEditFieldValueChanged, true);
            app.NumberofpulsetoreceiveEditField.Value = 8;
            app.Label_3 = uilabel(app.SendandreceivePanel);
            app.Label_3.Text = '1~127';
            app.MaxofTimeofFlightusDropDownLabel = uilabel(app.SendandreceivePanel);
            app.MaxofTimeofFlightusDropDownLabel.Text = 'Max. of Time of Flight (us)';
            app.MaxofTimeofFlightusDropDown = uidropdown(app.SendandreceivePanel);
            app.MaxofTimeofFlightusDropDown.Items = {'64', '128', '256', '512', '1024', '2048', '4096', '', ''};
            app.MaxofTimeofFlightusDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', ''};
            app.MaxofTimeofFlightusDropDown.ValueChangedFcn = createCallbackFcn(app, @MaxofTimeofFlightusDropDownValueChanged, true);
            app.MaxofTimeofFlightusDropDown.Value = '2';
            app.TriggerPanel = uipanel(app.ConfigurationTab);
            app.TriggerPanel.Title = 'Trigger';
            app.GatingTimeusEditFieldLabel = uilabel(app.TriggerPanel);
            app.GatingTimeusEditFieldLabel.Text = 'Gating Time (us)';
            app.GatingTimeusEditField = uieditfield(app.TriggerPanel, 'numeric');
            app.GatingTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @GatingTimeusEditFieldValueChanged, true);
            app.GatingTimeusEditField.Value = 30;
            app.TriggerThresholdmVEditFieldLabel = uilabel(app.TriggerPanel);
            app.TriggerThresholdmVEditFieldLabel.Text = 'Trigger Threshold (mV)';
            app.TriggerThresholdmVEditField = uieditfield(app.TriggerPanel, 'numeric');
            app.TriggerThresholdmVEditField.ValueChangedFcn = createCallbackFcn(app, @TriggerThresholdmVEditFieldValueChanged, true);
            app.mV62mVLabel = uilabel(app.TriggerPanel);
            app.mV62mVLabel.Text = '-64 mV~62 mV';
            app.usNN3216Label = uilabel(app.TriggerPanel);
            app.usNN3216Label.Text = '0.25 us *N, N=3~2^16';
            app.UploadParametersButton = uibutton(app.ConfigurationTab, 'push');
            app.UploadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UploadParametersButtonPushed, true);
            app.UploadParametersButton.Text = 'Upload Parameters';
            app.UIAxes2_2 = uiaxes(app.ConfigurationTab);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.iTOFMeasurementsTab = uitab(app.TabGroup);
            app.iTOFMeasurementsTab.Title = 'iTOF Measurements';
            app.CaptureButton_2 = uibutton(app.iTOFMeasurementsTab, 'push');
            app.CaptureButton_2.ButtonPushedFcn = createCallbackFcn(app, @CaptureButton_2Pushed, true);
            app.CaptureButton_2.Text = {'Capture'; ''};
            app.UIAxes_2 = uiaxes(app.iTOFMeasurementsTab);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'TOF (nS)')
            ylabel(app.UIAxes_2, 'Number of Samples')
            app.TemperatureLabel = uilabel(app.iTOFMeasurementsTab);
            app.TemperatureLabel.Text = 'Temperature';
            app.dTOFMeasurementsTab = uitab(app.TabGroup);
            app.dTOFMeasurementsTab.Title = 'dTOF Measurements';
            app.UIAxes_4 = uiaxes(app.dTOFMeasurementsTab);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, 'TOF (nS)')
            ylabel(app.UIAxes_4, 'Number of Samples')
            app.TemperatureLabel_2 = uilabel(app.dTOFMeasurementsTab);
            app.TemperatureLabel_2.Text = 'Temperature';
            app.dTOFmeasurementsButton = uibutton(app.dTOFMeasurementsTab, 'push');
            app.dTOFmeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @dTOFmeasurementsButtonPushed, true);
            app.dTOFmeasurementsButton.Text = 'dTOF measurements';
            app.BuildReferenceButton = uibutton(app.dTOFMeasurementsTab, 'push');
            app.BuildReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @BuildReferenceButtonPushed, true);
            app.BuildReferenceButton.Text = 'Build Reference';
            app.ReferencesignalisnotavailableLabel = uilabel(app.dTOFMeasurementsTab);
            app.ReferencesignalisnotavailableLabel.Text = 'Reference signal is not available';
            app.CalibrationtemperatureTab = uitab(app.TabGroup);
            app.CalibrationtemperatureTab.Title = 'Calibration temperature';
            app.UIAxes_3 = uiaxes(app.CalibrationtemperatureTab);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, 'Temperature (Degree Celsius)')
            ylabel(app.UIAxes_3, 'Number of Samples')
            app.CalibrationButton = uibutton(app.CalibrationtemperatureTab, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Text = 'Calibration';
            app.TOFvsTimeTab = uitab(app.TabGroup);
            app.TOFvsTimeTab.Title = 'TOF vs Time';
            app.UIAxes_5 = uiaxes(app.TOFvsTimeTab);
            title(app.UIAxes_5, 'TOF over time')
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, 'iTOF (ns)')
            app.CaptureButton_3 = uibutton(app.TOFvsTimeTab, 'push');
            app.CaptureButton_3.ButtonPushedFcn = createCallbackFcn(app, @CaptureButton_3Pushed, true);
            app.CaptureButton_3.Text = {'Capture'; ''};
            app.STOPButton_2 = uibutton(app.TOFvsTimeTab, 'push');
            app.STOPButton_2.ButtonPushedFcn = createCallbackFcn(app, @STOPButton_2Pushed, true);
            app.STOPButton_2.Text = 'STOP';
            app.ClearButton_2 = uibutton(app.TOFvsTimeTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = {'Clear'; ''};
            app.DebugButton_2 = uibutton(app.TOFvsTimeTab, 'push');
            app.DebugButton_2.ButtonPushedFcn = createCallbackFcn(app, @DebugButton_2Pushed, true);
            app.DebugButton_2.Text = 'Debug';
            app.TOFTESTTab = uitab(app.TabGroup);
            app.TOFTESTTab.Title = 'TOF TEST';
            app.UIAxes = uiaxes(app.TOFTESTTab);
            title(app.UIAxes, 'Voltages over time')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.A0VEditFieldLabel = uilabel(app.TOFTESTTab);
            app.A0VEditFieldLabel.Text = 'A0 (V)';
            app.A0VEditField = uieditfield(app.TOFTESTTab, 'numeric');
            app.A0VEditField.ValueChangedFcn = createCallbackFcn(app, @A0VEditFieldValueChanged, true);
            app.CaptureButton = uibutton(app.TOFTESTTab, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = {'Capture'; ''};
            app.VoltageRangeDropDownLabel = uilabel(app.TOFTESTTab);
            app.VoltageRangeDropDownLabel.Text = 'Voltage Range';
            app.VoltageRangeDropDown = uidropdown(app.TOFTESTTab);
            app.VoltageRangeDropDown.Items = {'± 5 V', '± 2.5 V', '± 1.25 V', '± 0.625 V', '± 0.3125 V', '± 0.156 V', '± 0.0782 V'};
            app.VoltageRangeDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.VoltageRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltageRangeDropDownValueChanged, true);
            app.VoltageRangeDropDown.Value = '0';
            app.AutoScaleButton = uibutton(app.TOFTESTTab, 'push');
            app.AutoScaleButton.ButtonPushedFcn = createCallbackFcn(app, @AutoScaleButtonPushed, true);
            app.AutoScaleButton.Text = 'Auto Scale';
            app.SamplingRateLabel = uilabel(app.TOFTESTTab);
            app.SamplingRateLabel.Text = 'Sampling Rate';
            app.SamplingRateDropDown_2 = uidropdown(app.TOFTESTTab);
            app.SamplingRateDropDown_2.Items = {'2.5 SPS', '5 SPS', '10 SPS', '15 SPS', '25 SPS', '30 SPS', '50 SPS', '60 SPS', '100 SPS', '500 SPS', '1000 SPS', '2000 SPS', '3750 SPS', '7500 SPS', '15000 SPS'};
            app.SamplingRateDropDown_2.ItemsData = {'3', '19', '35', '51', '67', '83', '99', '115', '131', '147', '163', '177', '192', '208', '224', ''};
            app.SamplingRateDropDown_2.Value = '3';
            app.A1VEditFieldLabel = uilabel(app.TOFTESTTab);
            app.A1VEditFieldLabel.Text = 'A1 (V)';
            app.A1VEditField = uieditfield(app.TOFTESTTab, 'numeric');
            app.STOPButton = uibutton(app.TOFTESTTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = {'STOP'; ''};
            app.ExportButton = uibutton(app.TOFTESTTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = {'Export'; ''};
            app.A2VEditFieldLabel = uilabel(app.TOFTESTTab);
            app.A2VEditFieldLabel.Text = 'A2 (V)';
            app.A2VEditField = uieditfield(app.TOFTESTTab, 'numeric');
            app.A3VEditFieldLabel = uilabel(app.TOFTESTTab);
            app.A3VEditFieldLabel.Text = 'A3 (V)';
            app.A3VEditField = uieditfield(app.TOFTESTTab, 'numeric');
            app.A0ChannelCheckBox = uicheckbox(app.TOFTESTTab);
            app.A0ChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @A0ChannelCheckBoxValueChanged, true);
            app.A0ChannelCheckBox.Text = 'A0 Channel';
            app.A0ChannelCheckBox.Value = true;
            app.A1ChannelCheckBox = uicheckbox(app.TOFTESTTab);
            app.A1ChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @A1ChannelCheckBoxValueChanged, true);
            app.A1ChannelCheckBox.Text = 'A1 Channel';
            app.A2ChannelCheckBox = uicheckbox(app.TOFTESTTab);
            app.A2ChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @A2ChannelCheckBoxValueChanged, true);
            app.A2ChannelCheckBox.Text = 'A2 Channel';
            app.A3ChannelCheckBox = uicheckbox(app.TOFTESTTab);
            app.A3ChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @A3ChannelCheckBoxValueChanged, true);
            app.A3ChannelCheckBox.Text = 'A3 Channel';
            app.slowerforhighaccuracyLabel = uilabel(app.TOFTESTTab);
            app.slowerforhighaccuracyLabel.Text = 'slower for high accuracy';
            app.ClearButton = uibutton(app.TOFTESTTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = {'Clear'; ''};
            app.ReversePolarityAveragingCheckBox = uicheckbox(app.TOFTESTTab);
            app.ReversePolarityAveragingCheckBox.Text = 'Reverse Polarity Averaging';
            app.EnableBufferCheckBox = uicheckbox(app.TOFTESTTab);
            app.EnableBufferCheckBox.Text = 'Enable Buffer';
            app.EnableBufferCheckBox.Value = true;
            app.DocTab = uitab(app.TabGroup);
            app.DocTab.Title = 'Doc.';
            app.Label = uilabel(app.DocTab);
            app.Label.Text = {'1. When more than one channels are selected, the total sampling rate may not be reached the specific value, due to the communication overhead;'; ''; '2. The real time plot window does not show all the data, when sampling rate is high, but all the data are in the buffers which can be exported;'; ''; '3. When sampling at 30k, the adc output 0.12 MBytes data per second, the buffer file will be large for long time sampling, the default buffer '; '     size can contain 100s sampling;'; ''; '4. for small signal measurements, noise can couple through the wires as  antena, For weak signal, keep the source impedance low, use shielded cable, '; 'reduce the loop area of tested object and wires, use faraday case to isolate the whole system'; 'from emi'; ''; '5. Use a battery drived ldo as AVDD, the noise performance is quite ok for low and high sampling rate, but near 1000 sps, the noise is very strong'; ''; '6. Design a new circuit; and pcb, use a analog front end to scale the input signal;'; ''; '7. Auto detection of the device'; ''; '8. For multichannel operation, it is better to use a low data rate; you can compare the conversion results '; ''; '9. Reverse the polarity can mitigate the thermal electric effect'; ''; ''};
            app.DebugButton = uibutton(app.DocTab, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = {'Debug'; ''};
            app.CalibrationTDCTab = uitab(app.TabGroup);
            app.CalibrationTDCTab.Title = 'Calibration TDC';
            app.CalibrationofTDCPanel = uipanel(app.CalibrationTDCTab);
            app.CalibrationofTDCPanel.Title = 'Calibration of TDC';
            app.GatingTimeusEditField_4Label = uilabel(app.CalibrationofTDCPanel);
            app.GatingTimeusEditField_4Label.Text = 'Gating Time (us)';
            app.GatingTimeusEditField_4 = uieditfield(app.CalibrationofTDCPanel, 'numeric');
            app.GatingTimeusEditField_4.Value = 2;
            app.TriggerThresholdmVEditField_4Label = uilabel(app.CalibrationofTDCPanel);
            app.TriggerThresholdmVEditField_4Label.Text = 'Trigger Threshold (mV)';
            app.TriggerThresholdmVEditField_4 = uieditfield(app.CalibrationofTDCPanel, 'numeric');
            app.TriggerThresholdmVEditField_4.Value = 2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = TOF

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VoltageMeter)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VoltageMeter)
        end
    end
end